classdef Behaviour_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BehaviourToolboxUIFigure        matlab.ui.Figure
        LoadExistingAnalysisButton      matlab.ui.control.Button
        StartNewAnalysisButton          matlab.ui.control.Button
        SaveAnalysisButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SelectBrowseROIsButton          matlab.ui.control.Button
        ShowAnalyzeButton               matlab.ui.control.Button
        ForceUpdateCheckBox             matlab.ui.control.CheckBox
        UpdateTableButton               matlab.ui.control.Button
        PauseforeachexperimentCheckBox  matlab.ui.control.CheckBox
        ExperimentsListBoxLabel         matlab.ui.control.Label
        ExperimentsListBox              matlab.ui.control.ListBox
        CheckfornewfilesButton          matlab.ui.control.Button
    end


    properties (Access = public)
        Video_path
        Experiment_set % Description
        failed_analysis
        failed_loading
        splitvideo
        full_table
        expe_table
    end

    methods (Access = private)
    
        function enable_analysis(app, status)
            if status
                status = 'On';
            else
                status = 'Off';
            end
            
            app.UITable.Enable = status;
            app.SelectBrowseROIsButton.Enable = status;
            app.ShowAnalyzeButton.Enable = status;
            app.ForceUpdateCheckBox.Enable = status;
            app.SaveAnalysisButton.Enable = status;
            app.UpdateTableButton.Enable = status;
            app.PauseforeachexperimentCheckBox.Enable = status;
            app.ExperimentsListBoxLabel.Enable = status;
            app.ExperimentsListBox.Enable = status;
        end

        function [filter_list, value,selection] = get_subset(app)
            if numel(app.ExperimentsListBox.Value) ==  numel(app.ExperimentsListBox.Items)
                filter_list = '';
                value = 1:numel(app.ExperimentsListBox.Items);
                selection = app.ExperimentsListBox.Value;
            else
                selection = app.ExperimentsListBox.Value;
                value = find(ismember(app.ExperimentsListBox.Items, app.ExperimentsListBox.Value));
                match = ismember(app.full_table(:,8),selection);
                list = app.full_table(match,1:2);
                filter_list = unique(strrep(join(strcat(list,'/'), 2), ' ', ''));
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'behaviour_GUI', app);
        end

        % Button pushed function: LoadExistingAnalysisButton
        function LoadExistingAnalysisButtonPushed(app, event)
            selection = uigetfile('Load previous analysis','*.mat');
            if any(selection)
                app.Experiment_set = load(selection);
                app.Experiment_set = app.Experiment_set.analysis;
                app.enable_analysis(true);
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: StartNewAnalysisButton
        function StartNewAnalysisButtonPushed(app, event)
            selection = uigetdir(app.Video_path, 'Select Video Folder');
            if any(selection)
                app.Video_path = [selection, '\'];     
                app.enable_analysis(false);
                app.Experiment_set = [];
                %assignin('base', 'all_experiments_output', []);
                app.SelectBrowseROIsButton.Enable = 'On';
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            analysis = app.Experiment_set;
            uisave('analysis');
        end

        % Button pushed function: SelectBrowseROIsButton
        function SelectBrowseROIsButtonPushed(app, event)
            if isempty(app.Video_path)
                app.StartNewAnalysisButtonPushed();
            end
            
            %% Get any subset of experiments that would be selected
            [filter_list, value, selection] = get_subset(app);
            
            %% Select MI ROI for the current selection
            [result, app.failed_loading, app.splitvideo] = batch_select_video_ROIs(app.Video_path, app.Experiment_set, '', '' ,filter_list, '');
            
            %% Update data set with the new ROI set
            if isempty(filter_list)
                app.Experiment_set = result;
            else
                app.Experiment_set(value) = result;
            end
            %app.Experiment_set = evalin('base','all_experiments_output');
            
            %% Update full data table with any new values
            app.enable_analysis(true);
            app.UpdateTableButtonPushed();
            
            %% If there was an initial selection ,resore it
            if ~isempty(filter_list)
                app.ExperimentsListBox.Value = selection;
                app.ExperimentsListBoxValueChanged();
            end
        end

        % Button pushed function: ShowAnalyzeButton
        function ShowAnalyzeButtonPushed(app, event)
            %% Get any subset of experiments that would be selected
            [filter_list, value, selection] = get_subset(app);
            
            [result , app.failed_analysis] = batch_measure_MIs_from_ROIs(app.Experiment_set(value), app.ForceUpdateCheckBox.Value, true, app.PauseforeachexperimentCheckBox.Value);
            app.Experiment_set(value) = result;
            app.UpdateTableButtonPushed();
            
            %% If there was an initial selection ,resore it
            if ~isempty(filter_list)
                app.ExperimentsListBox.Value = selection;
                app.ExperimentsListBoxValueChanged();
            end
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            app.full_table = {};
            app.expe_table = {};

            for exp = 1:numel(app.Experiment_set)
                experiment = app.Experiment_set{exp};
                exp_name = strsplit(experiment.fnames{1}{1},'/');
                exp_name = strrep(join(strcat(exp_name(end-4:end-3),'/')), ' ', '');
                level = size(app.full_table, 1);
                app.expe_table(end+1,1) = exp_name;%{num2str(exp)};
                for videotype = 1:numel(experiment.fnames)
                    files_current_type = experiment.fnames{videotype};
                    for rec = 1:numel(files_current_type)
                        recording = files_current_type{rec};
                        name = strsplit(recording,'/');

                        if ~isempty(experiment.MI{videotype}) && numel(experiment.MI) >= videotype && numel(experiment.MI{videotype}) >= rec && ~isempty(experiment.MI{videotype}{rec})
                            app.full_table{level+rec,7}(videotype) = numel(experiment.MI{videotype}{rec});
                        else
                            app.full_table{level+rec,7}(videotype) = 0;
                        end

                        app.full_table(level+rec,1:3) = [name(end-4), name(end-3), {name{end-2}(8:15)}];
                        if contains(name(end), 'Eye')
                            app.full_table(level+rec,4) = {any([app.full_table{level+rec,4}, 1])};
                        else
                            app.full_table(level+rec,4) = {any([app.full_table{level+rec,4}, 0])};
                        end
                        if contains(name(end), 'Body')
                            app.full_table(level+rec,5) = {any([app.full_table{level+rec,5}, 1])};
                        else
                            app.full_table(level+rec,5) = {any([app.full_table{level+rec,5}, 0])};
                        end
                        if contains(name(end), 'Whisk')
                            app.full_table(level+rec,6) = {any([app.full_table{level+rec,6}, 1])};
                        else
                            app.full_table(level+rec,6) = {any([app.full_table{level+rec,6}, 0])};
                        end
                        
                        app.full_table(level+rec,8) = exp_name; %to match expe_table
                    end
                end
            end
            if ~isempty(app.full_table)
                app.full_table(:,7) = cellfun(@(x) num2str(x),app.full_table(:,7), 'UniformOutput', false);
            end
            app.UITable.Data = app.full_table(:,1:7);
            app.ExperimentsListBox.Items = app.expe_table;
        end

        % Value changed function: ExperimentsListBox
        function ExperimentsListBoxValueChanged(app, event)
            value = app.ExperimentsListBox.Value;
            match = ismember(cell2mat(app.full_table(:,8)),value);
            app.UITable.Data = app.full_table(match,1:7);
        end

        % Button pushed function: CheckfornewfilesButton
        function CheckfornewfilesButtonPushed(app, event)
            app.Experiment_set = batch_select_video_ROIs(app.Video_path, app.Experiment_set, '', '' ,'', '');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BehaviourToolboxUIFigure = uifigure;
            app.BehaviourToolboxUIFigure.Name = 'Behaviour Toolbox';
            app.LoadExistingAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.LoadExistingAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingAnalysisButtonPushed, true);
            app.LoadExistingAnalysisButton.Text = 'Load Existing Analysis';
            app.StartNewAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.StartNewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewAnalysisButtonPushed, true);
            app.StartNewAnalysisButton.Text = 'Start New Analysis';
            app.SaveAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Enable = 'off';
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.UITable = uitable(app.BehaviourToolboxUIFigure);
            app.UITable.ColumnName = {'Day'; 'Experiment'; 'Recording'; 'Eye'; 'Body'; 'Whisker'; 'MI'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.SelectBrowseROIsButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SelectBrowseROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBrowseROIsButtonPushed, true);
            app.SelectBrowseROIsButton.Enable = 'off';
            app.SelectBrowseROIsButton.Text = 'Select/Browse ROIs';
            app.ShowAnalyzeButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.ShowAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAnalyzeButtonPushed, true);
            app.ShowAnalyzeButton.Enable = 'off';
            app.ShowAnalyzeButton.Text = 'Show/Analyze';
            app.ForceUpdateCheckBox = uicheckbox(app.BehaviourToolboxUIFigure);
            app.ForceUpdateCheckBox.Enable = 'off';
            app.ForceUpdateCheckBox.Text = 'Force Update';
            app.UpdateTableButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Enable = 'off';
            app.UpdateTableButton.Text = 'Update Table';
            app.PauseforeachexperimentCheckBox = uicheckbox(app.BehaviourToolboxUIFigure);
            app.PauseforeachexperimentCheckBox.Enable = 'off';
            app.PauseforeachexperimentCheckBox.Text = 'Pause for each experiment';
            app.ExperimentsListBoxLabel = uilabel(app.BehaviourToolboxUIFigure);
            app.ExperimentsListBoxLabel.Enable = 'off';
            app.ExperimentsListBoxLabel.Text = 'Experiments';
            app.ExperimentsListBox = uilistbox(app.BehaviourToolboxUIFigure);
            app.ExperimentsListBox.Items = {};
            app.ExperimentsListBox.Multiselect = 'on';
            app.ExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentsListBoxValueChanged, true);
            app.ExperimentsListBox.Enable = 'off';
            app.ExperimentsListBox.Value = {};
            app.CheckfornewfilesButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.CheckfornewfilesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckfornewfilesButtonPushed, true);
            app.CheckfornewfilesButton.Text = 'Check for new files';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Behaviour_analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BehaviourToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BehaviourToolboxUIFigure)
        end
    end
end